classdef Interfaz_TF < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EvaluaciondeDesempeoUIFigure    matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        PrincipalTab                    matlab.ui.container.Tab
        Image8                          matlab.ui.control.Image
        PresentadoporJoannaCarrionKatherineCondorJenaCuevaLabel  matlab.ui.control.Label
        TextArea                        matlab.ui.control.TextArea
        TRABAJOFINALTF202402Label       matlab.ui.control.Label
        TotalBotellasAmarillasEditField  matlab.ui.control.EditField
        TotalBotellasAmarillasEditFieldLabel  matlab.ui.control.Label
        TotalBotellasAzulesEditField    matlab.ui.control.EditField
        TotalBotellasAzulesEditFieldLabel  matlab.ui.control.Label
        TotalBotellasVerdesEditField    matlab.ui.control.EditField
        TotalBotellasVerdesEditFieldLabel  matlab.ui.control.Label
        IniciarProcesamientoButton      matlab.ui.control.Button
        TotalBotellasRojasEditField     matlab.ui.control.EditField
        TotalBotellasRojasEditFieldLabel  matlab.ui.control.Label
        ImagenconlosObjetosDetectadosLabel  matlab.ui.control.Label
        VideoenVivoLabel                matlab.ui.control.Label
        CapturarImagenButton            matlab.ui.control.Button
        PrendercamaraButton             matlab.ui.control.Button
        ImagenconlosObjetosDetectados   matlab.ui.control.UIAxes
        VideoAxes                       matlab.ui.control.UIAxes
        ProcesoTab                      matlab.ui.container.Tab
        PresentadoporJoannaCarrionKatherineCondorJenaCuevaLabel_2  matlab.ui.control.Label
        Image8_2                        matlab.ui.control.Image
        Image7                          matlab.ui.control.Image
        Image6                          matlab.ui.control.Image
        Image5                          matlab.ui.control.Image
        ImagenRecortadaLabel            matlab.ui.control.Label
        ImagenRecortada                 matlab.ui.control.Image
        ImagenOscurecidaLabel           matlab.ui.control.Label
        ImagenOscurecida                matlab.ui.control.Image
        ImagenCapturadaLabel            matlab.ui.control.Label
        ImagenCapturada                 matlab.ui.control.Image
        MascaraRojaTab                  matlab.ui.container.Tab
        PresentadoporJoannaCarrionKatherineCondorJenaCuevaLabel_3  matlab.ui.control.Label
        Image8_3                        matlab.ui.control.Image
        MASCARASINRUIDOROJALabel        matlab.ui.control.Label
        LLENADODEAGUJEROSROJALabel      matlab.ui.control.Label
        MASCARALIMPIAROJALabel          matlab.ui.control.Label
        MASCARAINDIVIDUALROJALabel      matlab.ui.control.Label
        MascaraRojaLimpia               matlab.ui.control.UIAxes
        MascaraRojaLlenadoAgujeros      matlab.ui.control.UIAxes
        MascaraRojasinRuido             matlab.ui.control.UIAxes
        MascaraRoja                     matlab.ui.control.UIAxes
        MascaraVerdeTab                 matlab.ui.container.Tab
        PresentadoporJoannaCarrionKatherineCondorJenaCuevaLabel_4  matlab.ui.control.Label
        Image8_4                        matlab.ui.control.Image
        MASCARASINRUIDOVERDELabel       matlab.ui.control.Label
        LLENADODEAGUJEROSVERDELabel     matlab.ui.control.Label
        MASCARALIMPIAVERDELabel         matlab.ui.control.Label
        MASCARAINDIVIDUALVERDELabel     matlab.ui.control.Label
        MascaraVerdeLlenadoAgujeros     matlab.ui.control.UIAxes
        MascaraVerdesinRuido            matlab.ui.control.UIAxes
        MascaraVerdeLimpia              matlab.ui.control.UIAxes
        MascaraVerde                    matlab.ui.control.UIAxes
        MascaraAzulTab                  matlab.ui.container.Tab
        PresentadoporJoannaCarrionKatherineCondorJenaCuevaLabel_5  matlab.ui.control.Label
        Image8_5                        matlab.ui.control.Image
        MASCARASINRUIDOAZULLabel        matlab.ui.control.Label
        LLENADODEAGUJEROSAZULLabel      matlab.ui.control.Label
        MASCARALIMPIAAZULLabel          matlab.ui.control.Label
        MASCARAINDIVIDUALAZULLabel      matlab.ui.control.Label
        MascaraAzulLlenadoAgujeros      matlab.ui.control.UIAxes
        MascaraAzulsinRuido             matlab.ui.control.UIAxes
        MascaraAzulLimpia               matlab.ui.control.UIAxes
        MascaraAzul                     matlab.ui.control.UIAxes
        MascaraAmarillaTab              matlab.ui.container.Tab
        PresentadoporJoannaCarrionKatherineCondorJenaCuevaLabel_6  matlab.ui.control.Label
        Image8_6                        matlab.ui.control.Image
        MASCARASINRUIDOAMARILLALabel    matlab.ui.control.Label
        LLENADODEAGUJEROSAMARILLALabel  matlab.ui.control.Label
        MASCARALIMPIAAMARILLALabel      matlab.ui.control.Label
        MASCARAINDIVIDUALAMARILLALabel  matlab.ui.control.Label
        MascaraAmarillaLlenadoAgujeros  matlab.ui.control.UIAxes
        MascaraAmarillasinRuido         matlab.ui.control.UIAxes
        MascaraAmarillaLimpia           matlab.ui.control.UIAxes
        MascaraAmarilla                 matlab.ui.control.UIAxes
        MascaraAnaranjadaTab            matlab.ui.container.Tab
        PresentadoporJoannaCarrionKatherineCondorJenaCuevaLabel_7  matlab.ui.control.Label
        Image8_7                        matlab.ui.control.Image
        MASCARASINRUIDOANARANJADALabel  matlab.ui.control.Label
        LLENADODEAGUJEROSANARANJADALabel  matlab.ui.control.Label
        MASCARALIMPIAANARANJADALabel    matlab.ui.control.Label
        MASCARAINDIVIDUALANARANJADALabel  matlab.ui.control.Label
        MascaraNaranjaLimpia            matlab.ui.control.UIAxes
        MascaraNaranjaLlenadoAgujeros   matlab.ui.control.UIAxes
        MascaraNaranjasinRuido          matlab.ui.control.UIAxes
        MascaraNaranja                  matlab.ui.control.UIAxes
        MascaraCelesteTab               matlab.ui.container.Tab
        PresentadoporJoannaCarrionKatherineCondorJenaCuevaLabel_8  matlab.ui.control.Label
        Image8_8                        matlab.ui.control.Image
        MASCARASINRUIDOCELESTELabel     matlab.ui.control.Label
        LLENADODEAGUJEROSCELESTELabel   matlab.ui.control.Label
        MASCARALIMPIACELESTELabel       matlab.ui.control.Label
        MASCARAINDIVIDUALCELESTELabel   matlab.ui.control.Label
        MascaraCelesteLimpia            matlab.ui.control.UIAxes
        MascaraCelesteLlenadoAgujeros   matlab.ui.control.UIAxes
        MascaraCelestesinRuido          matlab.ui.control.UIAxes
        MascaraCeleste                  matlab.ui.control.UIAxes
        MascaraMarrnTab                 matlab.ui.container.Tab
        PresentadoporJoannaCarrionKatherineCondorJenaCuevaLabel_9  matlab.ui.control.Label
        Image8_9                        matlab.ui.control.Image
        MASCARASINRUIDOMARRNLabel       matlab.ui.control.Label
        LLENADODEAGUJEROSMARRNLabel     matlab.ui.control.Label
        MASCARALIMPIAMARRNLabel         matlab.ui.control.Label
        MASCARAINDIVIDUALMARRNLabel     matlab.ui.control.Label
        MascaraMarronLimpia             matlab.ui.control.UIAxes
        MascaraMarronLlenadoAgujeros    matlab.ui.control.UIAxes
        MascaraMarronsinRuido           matlab.ui.control.UIAxes
        MascaraMarron                   matlab.ui.control.UIAxes
        MascaraNegraTab                 matlab.ui.container.Tab
        PresentadoporJoannaCarrionKatherineCondorJenaCuevaLabel_10  matlab.ui.control.Label
        Image8_10                       matlab.ui.control.Image
        MASCARASINRUIDONEGRALabel       matlab.ui.control.Label
        LLENADODEAGUJEROSNEGRALabel     matlab.ui.control.Label
        MASCARALIMPIANEGRALabel         matlab.ui.control.Label
        MASCARAINDIVIDUALNEGRALabel     matlab.ui.control.Label
        MascaraNegraLimpia              matlab.ui.control.UIAxes
        MascaraNegraLlenadoAgujeros     matlab.ui.control.UIAxes
        MascaraNegrasinRuido            matlab.ui.control.UIAxes
        MascaraNegra                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)

        cam                 % Objeto de la cámara
        img_preview         % Variable para ver la cámara encendida
        img_captured        % Imagen capturada del en vivo

        red_mask
        green_mask
        blue_mask
        yellow_mask

        orange_mask
        sky_mask
        brown_mask
        black_mask

        red_mask_cleaned 
        green_mask_cleaned
        blue_mask_cleaned 
        yellow_mask_cleaned 

        orange_mask_cleaned 
        sky_mask_cleaned
        brown_mask_cleaned 
        black_mask_cleaned 

    end


    methods (Access = private)
        
        function setDefaults(app)

            app.TotalBotellasRojasEditField.Editable  = 'off';
            app.TotalBotellasVerdesEditField.Editable  = 'off';
            app.TotalBotellasAzulesEditField.Editable  = 'off';
            app.TotalBotellasAmarillasEditField.Editable  = 'off';


        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            setDefaults(app);
        end

        % Button pushed function: PrendercamaraButton
        function PrendercamaraButtonPushed(app, event)
          
            app.cam = videoinput('winvideo', '1', 'MJPG_1920x1080');           % Inicializar la cámara
    
            app.cam.FramesPerTrigger = 1;
            triggerconfig(app.cam, 'manual');                                 % Configura el disparo manual de la cámara

            app.img_preview = getsnapshot(app.cam);                           % Captura una imagen inicial para obtener el tamaño

            img = image(app.VideoAxes,zeros(size(app.img_preview),'uint8'));  % Para obersevar en UIAxes
            
            app.VideoAxes.XLim = [0, size(app.img_preview, 2)];               %-----------
            app.VideoAxes.YLim = [0, size(app.img_preview, 1)];               %-----------

            axis(app.VideoAxes,'image');

            preview(app.cam,img);                                             % Mostrar el video en vivo
            pause(2);

        end

        % Button pushed function: CapturarImagenButton
        function CapturarImagenButtonPushed(app, event)
            
           app.img_captured = getsnapshot(app.cam);                              % Capturar la imagen del video en vivo
           app.ImagenCapturada.ImageSource = app.img_captured;                       % Mostrar imagen al lado del en vivo

        end

        % Button pushed function: IniciarProcesamientoButton
        function IniciarProcesamientoButtonPushed(app, event)
            
            % Oscurecer la imagen ajustando la intensidad
            app.img_captured = app.img_captured * 0.9;       % Reducir la luminosidad de la imagen
            app.ImagenOscurecida.ImageSource = app.img_captured;


            % Recortar la imagen para enfocar en el área central (ajusta las coordenadas según necesites)
            x = 480;        % Coordenada x del recorte inicial
            y = 0;          % Coordenada y del recorte inicial
            width = 880;    % Ancho del recorte
            height = 650;   % Altura del recorte

            app.img_captured = imcrop(app.img_captured, [x y width height]);
            app.ImagenRecortada.ImageSource = app.img_captured;


%%      --- Detección de tapas de colores (rojo/verde/azul/amarillo) ---
                   
            % Umbrales de color para cada tapa
            % Ajuste de los umbrales basado en observación de impixelinfo
            app.red_mask = (app.img_captured(:,:,1) > 148 & app.img_captured(:,:,1) < 225) & ... 
                           (app.img_captured(:,:,2) > 34 & app.img_captured(:,:,2) < 67) & ...
                           (app.img_captured(:,:,3) > 44 & app.img_captured(:,:,3) < 98);
            imshow(app.red_mask,'Parent',  app.MascaraRoja);
            axis(app.MascaraRoja, 'off');

            app.green_mask = (app.img_captured(:,:,1) > 4 & app.img_captured(:,:,1) < 102) & ... 
                             (app.img_captured(:,:,2) > 84 & app.img_captured(:,:,2) < 155) & ...
                             (app.img_captured(:,:,3) > 49 & app.img_captured(:,:,3) < 98);
            imshow(app.green_mask,'Parent',  app.MascaraVerde);
            axis(app.MascaraVerde, 'off');

            app.blue_mask = (app.img_captured(:,:,1) > 10 & app.img_captured(:,:,1) < 79) & ... 
                            (app.img_captured(:,:,2) > 35 & app.img_captured(:,:,2) < 60) & ...
                            (app.img_captured(:,:,3) > 98 & app.img_captured(:,:,3) < 160);
            imshow(app.blue_mask,'Parent',  app.MascaraAzul);
            axis(app.MascaraAzul, 'off');

            app.yellow_mask = (app.img_captured(:,:,1) > 151 & app.img_captured(:,:,1) < 211) & ... 
                              (app.img_captured(:,:,2) > 130 & app.img_captured(:,:,2) < 196) & ...
                              (app.img_captured(:,:,3) > 37 & app.img_captured(:,:,3) < 115);
            imshow(app.yellow_mask,'Parent',  app.MascaraAmarilla);
            axis(app.MascaraAmarilla, 'off');

%%      --- Detección de líquidos de colores (naranja/celeste/marrón/negro) ---

            app.orange_mask = (app.img_captured(:,:,1) > 156 & app.img_captured(:,:,1) < 219) & ... 
                              (app.img_captured(:,:,2) > 48 & app.img_captured(:,:,2) < 101) & ...
                              (app.img_captured(:,:,3) > -1 & app.img_captured(:,:,3) < 41);
            imshow(app.orange_mask,'Parent',  app.MascaraNaranja);
            axis(app.MascaraNaranja, 'off');

            app.sky_mask = (app.img_captured(:,:,1) > -1 & app.img_captured(:,:,1) < 32) & ... 
                           (app.img_captured(:,:,2) > 74 & app.img_captured(:,:,2) < 120) & ... 
                           (app.img_captured(:,:,3) > 134 & app.img_captured(:,:,3) < 197);
            imshow(app.sky_mask,'Parent',  app.MascaraCeleste);
            axis(app.MascaraCeleste, 'off');

            app.brown_mask = (app.img_captured(:,:,1) > 69 & app.img_captured(:,:,1) < 123) & ... 
                             (app.img_captured(:,:,2) > 49 & app.img_captured(:,:,2) < 88) & ... 
                             (app.img_captured(:,:,3) > -1 & app.img_captured(:,:,3) < 59);
            imshow(app.brown_mask,'Parent',  app.MascaraMarron);
            axis(app.MascaraMarron, 'off');

            app.black_mask = (app.img_captured(:,:,1) > -1 & app.img_captured(:,:,1) < 24) & ... 
                             (app.img_captured(:,:,2) > 10 & app.img_captured(:,:,2) < 38) & ... 
                             (app.img_captured(:,:,3) > 14 & app.img_captured(:,:,3) < 59);
            imshow(app.black_mask,'Parent',  app.MascaraNegra);
            axis(app.MascaraNegra, 'off');     


%%      --- Operación de cierre morfológico para eliminar agujeros ---

            se = strel('disk', 7);                                  % Estructura de elemento con un disco de radio
            app.red_mask_cleaned = imclose(app.red_mask, se);       % Cierre morfológico para rojo
            app.green_mask_cleaned = imclose(app.green_mask, se);   % Cierre morfológico para verde
            app.blue_mask_cleaned = imclose(app.blue_mask, se);     % Cierre morfológico para azul
            app.yellow_mask_cleaned = imclose(app.yellow_mask, se); % Cierre morfológico para amarillo\

            app.orange_mask_cleaned = imclose(app.orange_mask, se); % Cierre morfológico para naranja
            app.sky_mask_cleaned = imclose(app.sky_mask, se);       % Cierre morfológico para celeste
            app.brown_mask_cleaned = imclose(app.brown_mask, se);   % Cierre morfológico para marrón
            app.black_mask_cleaned = imclose(app.black_mask, se);   % Cierre morfológico para negro


            imshow(app.red_mask_cleaned,'Parent',  app.MascaraRojaLimpia);
            axis(app.MascaraRojaLimpia, 'off');
            imshow(app.green_mask_cleaned,'Parent',  app.MascaraVerdeLimpia);
            axis(app.MascaraVerdeLimpia, 'off');
            imshow(app.blue_mask_cleaned,'Parent',  app.MascaraAzulLimpia);
            axis(app.MascaraAzulLimpia, 'off');
            imshow(app.yellow_mask_cleaned,'Parent',  app.MascaraAmarillaLimpia);
            axis(app.MascaraAmarillaLimpia, 'off');

            imshow(app.orange_mask_cleaned,'Parent',  app.MascaraNaranjaLimpia);
            axis(app.MascaraNaranjaLimpia, 'off');
            imshow(app.sky_mask_cleaned,'Parent',  app.MascaraCelesteLimpia);
            axis(app.MascaraCelesteLimpia, 'off');
            imshow(app.brown_mask_cleaned,'Parent',  app.MascaraMarronLimpia);
            axis(app.MascaraMarronLimpia, 'off');
            imshow(app.black_mask_cleaned,'Parent',  app.MascaraNegraLimpia);
            axis(app.MascaraNegraLimpia, 'off');


%%      --- Llenado de agujeros en las máscaras de color ---

            app.red_mask_cleaned = imfill(app.red_mask_cleaned, 'holes');       % Llenar agujeros en máscara roja
            app.green_mask_cleaned = imfill(app.green_mask_cleaned, 'holes');   % Llenar agujeros en máscara verde
            app.blue_mask_cleaned = imfill(app.blue_mask_cleaned, 'holes');     % Llenar agujeros en máscara azul
            app.yellow_mask_cleaned = imfill(app.yellow_mask_cleaned, 'holes'); % Llenar agujeros en máscara amarilla

            app.orange_mask_cleaned = imfill(app.orange_mask_cleaned, 'holes'); % Llenar agujeros en máscara naranja
            app.sky_mask_cleaned = imfill(app.sky_mask_cleaned, 'holes');       % Llenar agujeros en máscara celeste
            app.brown_mask_cleaned = imfill(app.brown_mask_cleaned, 'holes');   % Llenar agujeros en máscara marrón
            app.black_mask_cleaned = imfill(app.black_mask_cleaned, 'holes');   % Llenar agujeros en máscara negra


            imshow(app.red_mask_cleaned,'Parent',  app.MascaraRojaLlenadoAgujeros);
            axis(app.MascaraRojaLlenadoAgujeros, 'off');
            imshow(app.green_mask_cleaned,'Parent',  app.MascaraVerdeLlenadoAgujeros);
            axis(app.MascaraVerdeLlenadoAgujeros, 'off');
            imshow(app.blue_mask_cleaned,'Parent',  app.MascaraAzulLlenadoAgujeros);
            axis(app.MascaraAzulLlenadoAgujeros, 'off');
            imshow(app.yellow_mask_cleaned,'Parent',  app.MascaraAmarillaLlenadoAgujeros);
            axis(app.MascaraAmarillaLlenadoAgujeros, 'off');

            imshow(app.orange_mask_cleaned,'Parent',  app.MascaraNaranjaLlenadoAgujeros);
            axis(app.MascaraNaranjaLlenadoAgujeros, 'off');
            imshow(app.sky_mask_cleaned,'Parent',  app.MascaraCelesteLlenadoAgujeros);
            axis(app.MascaraCelesteLlenadoAgujeros, 'off');
            imshow(app.brown_mask_cleaned,'Parent',  app.MascaraMarronLlenadoAgujeros);
            axis(app.MascaraMarronLlenadoAgujeros, 'off');
            imshow(app.black_mask_cleaned,'Parent',  app.MascaraNegraLlenadoAgujeros);
            axis(app.MascaraNegraLlenadoAgujeros, 'off');


%%      --- Eliminación de pequeñas regiones de píxeles (ruido) en las máscaras ---
           
            min_area = 250; % Tamaño mínimo de área en píxeles
            app.red_mask_cleaned = bwareaopen(app.red_mask_cleaned, min_area);
            app.green_mask_cleaned = bwareaopen(app.green_mask_cleaned, min_area);
            app.blue_mask_cleaned = bwareaopen(app.blue_mask_cleaned, min_area);           
            app.yellow_mask_cleaned = bwareaopen(app.yellow_mask_cleaned, min_area);

            app.orange_mask_cleaned = bwareaopen(app.orange_mask_cleaned, min_area);
            app.sky_mask_cleaned = bwareaopen(app.sky_mask_cleaned, min_area);
            app.brown_mask_cleaned = bwareaopen(app.brown_mask_cleaned, min_area);
            app.black_mask_cleaned = bwareaopen(app.black_mask_cleaned, min_area);


            imshow(app.red_mask_cleaned,'Parent',  app.MascaraRojasinRuido);
            axis(app.MascaraRojasinRuido, 'off');
            imshow(app.green_mask_cleaned,'Parent',  app.MascaraVerdesinRuido);
            axis(app.MascaraVerdesinRuido, 'off');
            imshow(app.blue_mask_cleaned,'Parent',  app.MascaraAzulsinRuido);
            axis(app.MascaraAzulsinRuido, 'off');
            imshow(app.yellow_mask_cleaned,'Parent',  app.MascaraAmarillasinRuido);
            axis(app.MascaraAmarillasinRuido, 'off');

            imshow(app.orange_mask_cleaned,'Parent',  app.MascaraNaranjasinRuido);
            axis(app.MascaraNaranjasinRuido, 'off');
            imshow(app.sky_mask_cleaned,'Parent',  app.MascaraCelestesinRuido);
            axis(app.MascaraCelestesinRuido, 'off');
            imshow(app.brown_mask_cleaned,'Parent',  app.MascaraMarronsinRuido);
            axis(app.MascaraMarronsinRuido, 'off');
            imshow(app.black_mask_cleaned,'Parent',  app.MascaraNegrasinRuido);
            axis(app.MascaraNegrasinRuido, 'off');


            % Detección de objetos en la máscara roja
            [red_labels, ~] = bwlabel(app.red_mask_cleaned);                      % Etiquetar objetos conectados en la máscara roja
            red_props = regionprops(red_labels, 'BoundingBox', 'Centroid');       % Obtener propiedades
            
            % Detección de objetos en la máscara verde
            [green_labels, ~] = bwlabel(app.green_mask_cleaned);                  % Etiquetar objetos conectados en la máscara verde
            green_props = regionprops(green_labels, 'BoundingBox', 'Centroid');   % Obtener propiedades
            
            % Detección de objetos en la máscara azul
            [blue_labels, ~] = bwlabel(app.blue_mask_cleaned);                    % Etiquetar objetos conectados en la máscara azul
            blue_props = regionprops(blue_labels, 'BoundingBox', 'Centroid');     % Obtener propiedades
            
            % Detección de objetos en la máscara amarilla
            [yellow_labels, ~] = bwlabel(app.yellow_mask_cleaned);                % Etiquetar objetos conectados en la máscara amarilla
            yellow_props = regionprops(yellow_labels, 'BoundingBox', 'Centroid'); % Obtener propiedades


            % Detección de objetos en la máscara naranja
            [orange_labels, ~] = bwlabel(app.orange_mask_cleaned);                    % Etiquetar objetos conectados en la máscara naranja
            orange_props = regionprops(orange_labels, 'BoundingBox', 'Centroid'); % Obtener propiedades
            
            % Detección de objetos en la máscara celeste
            [sky_labels, ~] = bwlabel(app.sky_mask_cleaned);                          % Etiquetar objetos conectados en la máscara celeste
            sky_props = regionprops(sky_labels, 'BoundingBox', 'Centroid');       % Obtener propiedades
            
            % Detección de objetos en la máscara marrón
            [brown_labels, ~] = bwlabel(app.brown_mask_cleaned);                      % Etiquetar objetos conectados en la máscara marrón
            brown_props = regionprops(brown_labels, 'BoundingBox', 'Centroid');   % Obtener propiedades
            
            % Detección de objetos en la máscara negra
            [black_labels, ~] = bwlabel(app.black_mask_cleaned);                      % Etiquetar objetos conectados en la máscara negra
            black_props = regionprops(black_labels, 'BoundingBox', 'Centroid');   % Obtener propiedades


%%      --- Mostrar la imagen original con los objetos detectados ---

            imshow(app.img_captured,'Parent',  app.ImagenconlosObjetosDetectados);
            axis(app.ImagenconlosObjetosDetectados, 'off');

            % Dibujar cuadro y etiquetas en el UIAxes
            hold(app.ImagenconlosObjetosDetectados, 'on');  % Mantener la imagen y los objetos juntos


            % Inicializar contador de identificación y contadores de cada color
            id_counter = 1;
            all_ids = [];

            % Inicializar contadores por color
            red_count = 0;
            green_count = 0;
            blue_count = 0;
            yellow_count = 0;
    

            % Lógica de clasificación y visualización
            props_list = {red_props, green_props, blue_props, yellow_props};
            color_names = ["Rojo", "Verde", "Azul", "Amarillo"];
            color_edges = ['r', 'g', 'b', 'y'];

            % Mostrar detecciones y medir altura - Relación píxeles a centímetros
            f1_pixels_to_cm = 0.0374; % Fila 1
            f2_pixels_to_cm = 0.0542; % Fila 2


            textoSalida = "";


%%      --- Inicializar lista de IDs con líquidos detectados ---
            ids_with_liquid = []; 
            
            % Verificar alineación de líquidos con IDs existentes
            liquids_masks = {orange_props, sky_props, brown_props, black_props};
            liquids_names = ["Naranja", "Celeste", "Marrón", "Negro"];


%%      --- Procesar cada conjunto de propiedades por color ---

            for color_idx = 1:length(props_list)
                props = props_list{color_idx};
                color_count = 0;
            
                for k = 1:length(props)
                    y_min = props(k).BoundingBox(2);
                    size_label = 'Pequeña';
                    row_label = 'Fila 2';
            
                    % Clasificación por tamaño y fila
                    if y_min < 200
                        size_label = 'Grande';
                        if y_min < 100
                            row_label = 'Fila 1';
                        end
                    elseif y_min < 240
                        row_label = 'Fila 1';
                    end
            

                    % Dibujar cuadro y etiquetas
                    rectangle('Position', props(k).BoundingBox, 'EdgeColor', color_edges(color_idx), 'LineWidth', 2,  'Parent', app.ImagenconlosObjetosDetectados);
                    centroid = props(k).Centroid;
                    text(centroid(1), centroid(2) - 33, sprintf('ID: %d', id_counter), 'Color', 'b', 'FontSize', 14, 'FontWeight', 'bold', 'Parent', app.ImagenconlosObjetosDetectados); 

                    % Almacenar ID, color y centroide
                    all_ids = [all_ids; struct('ID', id_counter, 'Color', color_names(color_idx), 'Centroid', centroid, 'Size', size_label, 'Row', row_label)];
            
                    % Imprimir información en el Command Window
                    %fprintf('ID: %d | Color tapa: %s | Tamaño: %s | Fila: %s\n', id_counter, color_names(color_idx), size_label, row_label);
            
                    % Incrementar contadores
                    id_counter = id_counter + 1;
                    color_count = color_count + 1;
                end
            
                % Actualizar conteos por color
                switch color_idx
                    case 1, red_count = color_count;
                    case 2, green_count = color_count;
                    case 3, blue_count = color_count;
                    case 4, yellow_count = color_count;
                end
            end
            

%%      --- Verificar alineación de máscaras naranjas con IDs existentes ---

            for i = 1:length(orange_props)
                orange_centroid = orange_props(i).Centroid;
                orange_bbox = orange_props(i).BoundingBox; 
            
                % Altura en píxeles y conversión a centímetros
                altura_pixels = orange_bbox(4);
            
                % Dibujar el centroide
                plot(orange_centroid(1), orange_centroid(2), 'o', 'MarkerSize', 10, 'MarkerEdgeColor', 'k', 'MarkerFaceColor', [1, 0.5, 0],   'Parent', app.ImagenconlosObjetosDetectados);
                text(orange_centroid(1) + 10, orange_centroid(2), 'Naranja', 'Color', [1, 0.5, 0], 'FontSize', 10, 'FontWeight', 'bold',   'Parent', app.ImagenconlosObjetosDetectados);
            
                % Verificar alineación en el eje X con otras máscaras
                aligned_ids = [];
                for j = 1:length(all_ids)
                    if abs(all_ids(j).Centroid(1) - orange_centroid(1)) < 30
                        aligned_ids = [aligned_ids; all_ids(j)];
                    end
                end
            
                % Si hay elementos alineados, realizar cálculo de altura
                if ~isempty(aligned_ids)
                    % Iterar sobre los IDs alineados
                    for k = 1:length(aligned_ids)
                        % Verificar que Row sea una cadena y realizar la comparación
                        if isfield(aligned_ids(k), 'Row') && (ischar(aligned_ids(k).Row) || isstring(aligned_ids(k).Row))
                            if strcmp(aligned_ids(k).Row, 'Fila 1') % Si está en Fila 1
                                altura_cm = altura_pixels * f1_pixels_to_cm;
                            elseif strcmp(aligned_ids(k).Row, 'Fila 2') % Si está en Fila 2
                                altura_cm = altura_pixels * f2_pixels_to_cm;
                            else
                                % Si no es 'Fila 1' ni 'Fila 2'
                                altura_cm = NaN; % Asignar NaN o alguna otra acción
                                disp('Fila no reconocida');
                            end
            
                            % Imprimir los resultados
                            texto1 = sprintf('ID: %d | Color tapa: %s | Tamaño: %s | Fila: %s | Altura: %.2f cm | Líquido: Naranja\n', ...
                                aligned_ids(k).ID, aligned_ids(k).Color, aligned_ids(k).Size, aligned_ids(k).Row, altura_cm);
                            
                            % Concatenar el texto para mostrar en el TextArea
                            textoSalida = textoSalida + texto1;

                        else
                            % Manejo del caso cuando no se encuentra el campo 'Row' o es inválido
                            disp('No se encuentra la fila en el objeto alineado.');
                        end
                    end
                else
                    fprintf('La detección naranja no está alineada con ninguna máscara.\n');
                end
            end
            

%%      --- Verificar alineación de máscaras celestes con IDs existentes ---

            for i = 1:length(sky_props)
                sky_centroid = sky_props(i).Centroid;
                sky_bbox = sky_props(i).BoundingBox;
            
                % Altura en píxeles y conversión a centímetros
                altura_pixels = sky_bbox(4);
            
                % Dibujar el centroide
                plot(sky_centroid(1), sky_centroid(2), 'o', 'MarkerSize', 10, 'MarkerEdgeColor', 'k', 'MarkerFaceColor', [1, 0.5, 0], 'Parent', app.ImagenconlosObjetosDetectados);
                text(sky_centroid(1) + 10, sky_centroid(2), 'Celeste', 'Color', [1, 0.5, 0], 'FontSize', 10, 'FontWeight', 'bold', 'Parent', app.ImagenconlosObjetosDetectados);
            
                % Verificar alineación en el eje X con otras máscaras
                aligned_ids = [];
                for j = 1:length(all_ids)
                    if abs(all_ids(j).Centroid(1) - sky_centroid(1)) < 30
                        aligned_ids = [aligned_ids; all_ids(j)];
                    end
                end
            
                % Si hay elementos alineados, realizar cálculo de altura
                if ~isempty(aligned_ids)
                    % Iterar sobre los IDs alineados
                    for k = 1:length(aligned_ids)
                        % Verificar que Row sea una cadena y realizar la comparación
                        if isfield(aligned_ids(k), 'Row') && (ischar(aligned_ids(k).Row) || isstring(aligned_ids(k).Row))
                            if strcmp(aligned_ids(k).Row, 'Fila 1') % Si está en Fila 1
                                altura_cm = altura_pixels * f1_pixels_to_cm;
                            elseif strcmp(aligned_ids(k).Row, 'Fila 2') % Si está en Fila 2
                                altura_cm = altura_pixels * f2_pixels_to_cm;
                            else
                                % Si no es 'Fila 1' ni 'Fila 2'
                                altura_cm = NaN; % Asignar NaN o alguna otra acción
                                disp('Fila no reconocida');
                            end
            
                            % Imprimir los resultados
                            texto2 = sprintf('ID: %d | Color tapa: %s | Tamaño: %s | Fila: %s | Altura: %.2f cm | Líquido: Celeste\n', ...
                                aligned_ids(k).ID, aligned_ids(k).Color, aligned_ids(k).Size, aligned_ids(k).Row, altura_cm);

                            % Concatenar el texto para mostrar en el TextArea
                            textoSalida = textoSalida + texto2;

                        else
                            % Manejo del caso cuando no se encuentra el campo 'Row' o es inválido
                            disp('No se encuentra la fila en el objeto alineado.');
                        end
                    end
                else
                    fprintf('La detección celeste no está alineada con ninguna máscara.\n');
                end
            end
            

%%      --- Verificar alineación de máscaras marrón con IDs existentes ---

            for i = 1:length(brown_props)
                brown_centroid = brown_props(i).Centroid;
                brown_bbox = brown_props(i).BoundingBox;
            
                % Altura en píxeles y conversión a centímetros
                altura_pixels = brown_bbox(4);
            
                % Dibujar el centroide
                plot(brown_centroid(1), brown_centroid(2), 'o', 'MarkerSize', 10, 'MarkerEdgeColor', 'k', 'MarkerFaceColor', [1, 0.5, 0], 'Parent', app.ImagenconlosObjetosDetectados);
                text(brown_centroid(1) + 10, brown_centroid(2), 'Marrón', 'Color', [1, 0.5, 0], 'FontSize', 10, 'FontWeight', 'bold', 'Parent', app.ImagenconlosObjetosDetectados);
            
                % Verificar alineación en el eje X con otras máscaras
                aligned_ids = [];
                for j = 1:length(all_ids)
                    if abs(all_ids(j).Centroid(1) - brown_centroid(1)) < 30
                        aligned_ids = [aligned_ids; all_ids(j)];
                    end
                end
            
                % Si hay elementos alineados, realizar cálculo de altura
                if ~isempty(aligned_ids)
                    % Iterar sobre los IDs alineados
                    for k = 1:length(aligned_ids)
                        % Verificar que Row sea una cadena y realizar la comparación
                        if isfield(aligned_ids(k), 'Row') && (ischar(aligned_ids(k).Row) || isstring(aligned_ids(k).Row))
                            if strcmp(aligned_ids(k).Row, 'Fila 1') % Si está en Fila 1
                                altura_cm = altura_pixels * f1_pixels_to_cm;
                            elseif strcmp(aligned_ids(k).Row, 'Fila 2') % Si está en Fila 2
                                altura_cm = altura_pixels * f2_pixels_to_cm;
                            else
                                % Si no es 'Fila 1' ni 'Fila 2'
                                altura_cm = NaN; % Asignar NaN o alguna otra acción
                                disp('Fila no reconocida');
                            end
            
                            % Imprimir los resultados
                            texto3 = sprintf('ID: %d | Color tapa: %s | Tamaño: %s | Fila: %s | Altura: %.2f cm | Líquido: Marrón\n', ...
                                aligned_ids(k).ID, aligned_ids(k).Color, aligned_ids(k).Size, aligned_ids(k).Row, altura_cm);

                            % Concatenar el texto para mostrar en el TextArea
                            textoSalida = textoSalida + texto3;

                        else
                            % Manejo del caso cuando no se encuentra el campo 'Row' o es inválido
                            disp('No se encuentra la fila en el objeto alineado.');
                        end
                    end
                else
                    fprintf('La detección marrón no está alineada con ninguna máscara.\n');
                end
            end
            

%%      --- Verificar alineación de máscaras negras con IDs existentes ---

            for i = 1:length(black_props)
                black_centroid = black_props(i).Centroid;
                black_bbox = black_props(i).BoundingBox; 
            
                % Altura en píxeles y conversión a centímetros
                altura_pixels = black_bbox(4);
            
                % Dibujar el centroide
                plot(black_centroid(1), black_centroid(2), 'o', 'MarkerSize', 10, 'MarkerEdgeColor', 'k', 'MarkerFaceColor', [1, 0.5, 0], 'Parent', app.ImagenconlosObjetosDetectados);
                text(black_centroid(1) + 10, black_centroid(2), 'Negra', 'Color', [1, 0.5, 0], 'FontSize', 10, 'FontWeight', 'bold', 'Parent', app.ImagenconlosObjetosDetectados);
            
                % Verificar alineación en el eje X con otras máscaras
                aligned_ids = [];
                for j = 1:length(all_ids)
                    if abs(all_ids(j).Centroid(1) - black_centroid(1)) < 30
                        aligned_ids = [aligned_ids; all_ids(j)];
                    end
                end
            
                % Si hay elementos alineados, realizar cálculo de altura
                if ~isempty(aligned_ids)
                    % Iterar sobre los IDs alineados
                    for k = 1:length(aligned_ids)
                        % Verificar que Row sea una cadena y realizar la comparación
                        if isfield(aligned_ids(k), 'Row') && (ischar(aligned_ids(k).Row) || isstring(aligned_ids(k).Row))
                            if strcmp(aligned_ids(k).Row, 'Fila 1') % Si está en Fila 1
                                altura_cm = altura_pixels * f1_pixels_to_cm;
                            elseif strcmp(aligned_ids(k).Row, 'Fila 2') % Si está en Fila 2
                                altura_cm = altura_pixels * f2_pixels_to_cm;
                            else
                                % Si no es 'Fila 1' ni 'Fila 2'
                                altura_cm = NaN; % Asignar NaN o alguna otra acción
                                disp('Fila no reconocida');
                            end
            
                            % Imprimir los resultados
                            texto4 = sprintf('ID: %d | Color tapa: %s | Tamaño: %s | Fila: %s | Altura: %.2f cm | Líquido: Negro\n', ...
                                aligned_ids(k).ID, aligned_ids(k).Color, aligned_ids(k).Size, aligned_ids(k).Row, altura_cm);

                            % Concatenar el texto para mostrar en el TextArea
                            textoSalida = textoSalida + texto4;

                        else
                            % Manejo del caso cuando no se encuentra el campo 'Row' o es inválido
                            disp('No se encuentra la fila en el objeto alineado.');
                        end
                    end
                else
                    fprintf('La detección negra no está alineada con ninguna máscara.\n');
                end
            end


%%      --- Verificar otros casos ---

            for liquid_idx = 1:length(liquids_masks)
                liquid_props = liquids_masks{liquid_idx};
                liquid_name = liquids_names(liquid_idx);
            
                for i = 1:length(liquid_props)
                    liquid_centroid = liquid_props(i).Centroid;
            
                    % Verificar alineación en el eje X con las tapas detectadas
                    aligned_ids = [];
                    for j = 1:length(all_ids)
                        if abs(all_ids(j).Centroid(1) - liquid_centroid(1)) < 30
                            aligned_ids = [aligned_ids; all_ids(j)];
                        end
                    end
            
                    % Agregar las IDs alineadas a la lista de IDs con líquidos
                    for k = 1:length(aligned_ids)
                        ids_with_liquid = [ids_with_liquid; aligned_ids(k).ID];
                    end
                end
            end
            
            % Eliminar duplicados de la lista de IDs con líquidos
            ids_with_liquid = unique(ids_with_liquid);
            
            % Determinar IDs sin líquidos
            ids_without_liquid = setdiff([all_ids.ID], ids_with_liquid);
            
            % Imprimir detalles de las IDs que no tienen líquido detectado
            if isempty(ids_without_liquid)
            else
                for i = 1:length(ids_without_liquid)
                    % Buscar los detalles en la estructura all_ids
                    id_info = all_ids([all_ids.ID] == ids_without_liquid(i));
                    
                    % Imprimir los resultados
                    texto5 = sprintf('ID: %d | Color tapa: %s | Tamaño: %s | Fila: %s\n', ...
                        id_info.ID, id_info.Color, id_info.Size, id_info.Row);

                        % Concatenar el texto para mostrar en el TextArea
                        textoSalida = textoSalida + texto5;
                end
            end


%%      --- Conteo de botellas y grafica con objetos encerrados ---

            % Liberar el hold para poder modificar los objetos en el futuro
            hold(app.ImagenconlosObjetosDetectados, 'off');

            app.TextArea.Value = textoSalida;

            app.TotalBotellasRojasEditField.Value = num2str(red_count);
            app.TotalBotellasVerdesEditField.Value = num2str(green_count);
            app.TotalBotellasAzulesEditField.Value = num2str(blue_count);
            app.TotalBotellasAmarillasEditField.Value = num2str(yellow_count);


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.EvaluaciondeDesempeoUIFigure = uifigure('Visible', 'off');
            app.EvaluaciondeDesempeoUIFigure.Name = 'Evaluacion de Desempeño';
            app.EvaluaciondeDesempeoUIFigure.Icon = fullfile(pathToMLAPP, 'UPC_logo_transparente.png');
            app.TabGroup = uitabgroup(app.EvaluaciondeDesempeoUIFigure);
            app.PrincipalTab = uitab(app.TabGroup);
            app.PrincipalTab.Title = 'Principal';
            app.VideoAxes = uiaxes(app.PrincipalTab);
            zlabel(app.VideoAxes, 'Z')
            app.ImagenconlosObjetosDetectados = uiaxes(app.PrincipalTab);
            zlabel(app.ImagenconlosObjetosDetectados, 'Z')
            app.PrendercamaraButton = uibutton(app.PrincipalTab, 'push');
            app.PrendercamaraButton.ButtonPushedFcn = createCallbackFcn(app, @PrendercamaraButtonPushed, true);
            app.PrendercamaraButton.Text = 'Prender cámara';
            app.CapturarImagenButton = uibutton(app.PrincipalTab, 'push');
            app.CapturarImagenButton.ButtonPushedFcn = createCallbackFcn(app, @CapturarImagenButtonPushed, true);
            app.CapturarImagenButton.Text = 'Capturar Imagen';
            app.VideoenVivoLabel = uilabel(app.PrincipalTab);
            app.VideoenVivoLabel.Text = 'Video en Vivo';
            app.ImagenconlosObjetosDetectadosLabel = uilabel(app.PrincipalTab);
            app.ImagenconlosObjetosDetectadosLabel.Text = 'Imagen con los Objetos Detectados';
            app.TotalBotellasRojasEditFieldLabel = uilabel(app.PrincipalTab);
            app.TotalBotellasRojasEditFieldLabel.Text = 'Total Botellas Rojas:';
            app.TotalBotellasRojasEditField = uieditfield(app.PrincipalTab, 'text');
            app.IniciarProcesamientoButton = uibutton(app.PrincipalTab, 'push');
            app.IniciarProcesamientoButton.ButtonPushedFcn = createCallbackFcn(app, @IniciarProcesamientoButtonPushed, true);
            app.IniciarProcesamientoButton.Text = 'Iniciar Procesamiento';
            app.TotalBotellasVerdesEditFieldLabel = uilabel(app.PrincipalTab);
            app.TotalBotellasVerdesEditFieldLabel.Text = 'Total Botellas Verdes:';
            app.TotalBotellasVerdesEditField = uieditfield(app.PrincipalTab, 'text');
            app.TotalBotellasAzulesEditFieldLabel = uilabel(app.PrincipalTab);
            app.TotalBotellasAzulesEditFieldLabel.Text = 'Total Botellas Azules:';
            app.TotalBotellasAzulesEditField = uieditfield(app.PrincipalTab, 'text');
            app.TotalBotellasAmarillasEditFieldLabel = uilabel(app.PrincipalTab);
            app.TotalBotellasAmarillasEditFieldLabel.Text = 'Total Botellas Amarillas:';
            app.TotalBotellasAmarillasEditField = uieditfield(app.PrincipalTab, 'text');
            app.TRABAJOFINALTF202402Label = uilabel(app.PrincipalTab);
            app.TRABAJOFINALTF202402Label.Text = 'TRABAJO FINAL (TF) 2024-02';
            app.TextArea = uitextarea(app.PrincipalTab);
            app.PresentadoporJoannaCarrionKatherineCondorJenaCuevaLabel = uilabel(app.PrincipalTab);
            app.PresentadoporJoannaCarrionKatherineCondorJenaCuevaLabel.Text = 'Presentado por: Joanna Carrión, Katherine Condor, Jean Cueva, Walther Retuerto, Anthony Santillán y Alexander Salazar                                                                                                                                       2024-02';
            app.Image8 = uiimage(app.PrincipalTab);
            app.Image8.ImageSource = fullfile(pathToMLAPP, 'UPC_logo_transparente.png');
            app.ProcesoTab = uitab(app.TabGroup);
            app.ProcesoTab.Title = 'Proceso';
            app.ImagenCapturada = uiimage(app.ProcesoTab);
            app.ImagenCapturadaLabel = uilabel(app.ProcesoTab);
            app.ImagenCapturadaLabel.Text = 'Imagen Capturada';
            app.ImagenOscurecida = uiimage(app.ProcesoTab);
            app.ImagenOscurecidaLabel = uilabel(app.ProcesoTab);
            app.ImagenOscurecidaLabel.Text = 'Imagen Oscurecida';
            app.ImagenRecortada = uiimage(app.ProcesoTab);
            app.ImagenRecortadaLabel = uilabel(app.ProcesoTab);
            app.ImagenRecortadaLabel.Text = 'Imagen Recortada';
            app.Image5 = uiimage(app.ProcesoTab);
            app.Image5.ImageSource = fullfile(pathToMLAPP, 'flecha.png');
            app.Image6 = uiimage(app.ProcesoTab);
            app.Image6.ImageSource = fullfile(pathToMLAPP, 'flechaizq.png');
            app.Image7 = uiimage(app.ProcesoTab);
            app.Image7.ImageSource = fullfile(pathToMLAPP, 'flecha.png');
            app.Image8_2 = uiimage(app.ProcesoTab);
            app.Image8_2.ImageSource = fullfile(pathToMLAPP, 'UPC_logo_transparente.png');
            app.PresentadoporJoannaCarrionKatherineCondorJenaCuevaLabel_2 = uilabel(app.ProcesoTab);
            app.PresentadoporJoannaCarrionKatherineCondorJenaCuevaLabel_2.Text = 'Presentado por: Joanna Carrión, Katherine Condor, Jean Cueva, Walther Retuerto, Anthony Santillán y Alexander Salazar                                                                                                                                       2024-02';
            app.MascaraRojaTab = uitab(app.TabGroup);
            app.MascaraRojaTab.Title = 'Mascara Roja';
            app.MascaraRoja = uiaxes(app.MascaraRojaTab);
            zlabel(app.MascaraRoja, 'Z')
            app.MascaraRoja.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.MascaraRoja.YTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.MascaraRojasinRuido = uiaxes(app.MascaraRojaTab);
            zlabel(app.MascaraRojasinRuido, 'Z')
            app.MascaraRojaLlenadoAgujeros = uiaxes(app.MascaraRojaTab);
            zlabel(app.MascaraRojaLlenadoAgujeros, 'Z')
            app.MascaraRojaLimpia = uiaxes(app.MascaraRojaTab);
            zlabel(app.MascaraRojaLimpia, 'Z')
            app.MASCARAINDIVIDUALROJALabel = uilabel(app.MascaraRojaTab);
            app.MASCARAINDIVIDUALROJALabel.Text = 'MASCARA INDIVIDUAL ROJA';
            app.MASCARALIMPIAROJALabel = uilabel(app.MascaraRojaTab);
            app.MASCARALIMPIAROJALabel.Text = 'MASCARA LIMPIA ROJA';
            app.LLENADODEAGUJEROSROJALabel = uilabel(app.MascaraRojaTab);
            app.LLENADODEAGUJEROSROJALabel.Text = 'LLENADO DE AGUJEROS ROJA';
            app.MASCARASINRUIDOROJALabel = uilabel(app.MascaraRojaTab);
            app.MASCARASINRUIDOROJALabel.Text = 'MASCARA SIN RUIDO ROJA';
            app.Image8_3 = uiimage(app.MascaraRojaTab);
            app.Image8_3.ImageSource = fullfile(pathToMLAPP, 'UPC_logo_transparente.png');
            app.PresentadoporJoannaCarrionKatherineCondorJenaCuevaLabel_3 = uilabel(app.MascaraRojaTab);
            app.PresentadoporJoannaCarrionKatherineCondorJenaCuevaLabel_3.Text = 'Presentado por: Joanna Carrión, Katherine Condor, Jean Cueva, Walther Retuerto, Anthony Santillán y Alexander Salazar                                                                                                                                       2024-02';
            app.MascaraVerdeTab = uitab(app.TabGroup);
            app.MascaraVerdeTab.Title = 'Mascara Verde';
            app.MascaraVerde = uiaxes(app.MascaraVerdeTab);
            zlabel(app.MascaraVerde, 'Z')
            app.MascaraVerde.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.MascaraVerde.YTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.MascaraVerdeLimpia = uiaxes(app.MascaraVerdeTab);
            zlabel(app.MascaraVerdeLimpia, 'Z')
            app.MascaraVerdesinRuido = uiaxes(app.MascaraVerdeTab);
            zlabel(app.MascaraVerdesinRuido, 'Z')
            app.MascaraVerdeLlenadoAgujeros = uiaxes(app.MascaraVerdeTab);
            zlabel(app.MascaraVerdeLlenadoAgujeros, 'Z')
            app.MASCARAINDIVIDUALVERDELabel = uilabel(app.MascaraVerdeTab);
            app.MASCARAINDIVIDUALVERDELabel.Text = 'MASCARA INDIVIDUAL VERDE';
            app.MASCARALIMPIAVERDELabel = uilabel(app.MascaraVerdeTab);
            app.MASCARALIMPIAVERDELabel.Text = 'MASCARA LIMPIA VERDE';
            app.LLENADODEAGUJEROSVERDELabel = uilabel(app.MascaraVerdeTab);
            app.LLENADODEAGUJEROSVERDELabel.Text = 'LLENADO DE AGUJEROS VERDE';
            app.MASCARASINRUIDOVERDELabel = uilabel(app.MascaraVerdeTab);
            app.MASCARASINRUIDOVERDELabel.Text = 'MASCARA SIN RUIDO VERDE';
            app.Image8_4 = uiimage(app.MascaraVerdeTab);
            app.Image8_4.ImageSource = fullfile(pathToMLAPP, 'UPC_logo_transparente.png');
            app.PresentadoporJoannaCarrionKatherineCondorJenaCuevaLabel_4 = uilabel(app.MascaraVerdeTab);
            app.PresentadoporJoannaCarrionKatherineCondorJenaCuevaLabel_4.Text = 'Presentado por: Joanna Carrión, Katherine Condor, Jean Cueva, Walther Retuerto, Anthony Santillán y Alexander Salazar                                                                                                                                       2024-02';
            app.MascaraAzulTab = uitab(app.TabGroup);
            app.MascaraAzulTab.Title = 'Mascara Azul';
            app.MascaraAzul = uiaxes(app.MascaraAzulTab);
            zlabel(app.MascaraAzul, 'Z')
            app.MascaraAzulLimpia = uiaxes(app.MascaraAzulTab);
            zlabel(app.MascaraAzulLimpia, 'Z')
            app.MascaraAzulsinRuido = uiaxes(app.MascaraAzulTab);
            zlabel(app.MascaraAzulsinRuido, 'Z')
            app.MascaraAzulLlenadoAgujeros = uiaxes(app.MascaraAzulTab);
            zlabel(app.MascaraAzulLlenadoAgujeros, 'Z')
            app.MASCARAINDIVIDUALAZULLabel = uilabel(app.MascaraAzulTab);
            app.MASCARAINDIVIDUALAZULLabel.Text = 'MASCARA INDIVIDUAL AZUL';
            app.MASCARALIMPIAAZULLabel = uilabel(app.MascaraAzulTab);
            app.MASCARALIMPIAAZULLabel.Text = 'MASCARA LIMPIA AZUL';
            app.LLENADODEAGUJEROSAZULLabel = uilabel(app.MascaraAzulTab);
            app.LLENADODEAGUJEROSAZULLabel.Text = 'LLENADO DE AGUJEROS AZUL';
            app.MASCARASINRUIDOAZULLabel = uilabel(app.MascaraAzulTab);
            app.MASCARASINRUIDOAZULLabel.Text = 'MASCARA SIN RUIDO AZUL';
            app.Image8_5 = uiimage(app.MascaraAzulTab);
            app.Image8_5.ImageSource = fullfile(pathToMLAPP, 'UPC_logo_transparente.png');
            app.PresentadoporJoannaCarrionKatherineCondorJenaCuevaLabel_5 = uilabel(app.MascaraAzulTab);
            app.PresentadoporJoannaCarrionKatherineCondorJenaCuevaLabel_5.Text = 'Presentado por: Joanna Carrión, Katherine Condor, Jean Cueva, Walther Retuerto, Anthony Santillán y Alexander Salazar                                                                                                                                       2024-02';
            app.MascaraAmarillaTab = uitab(app.TabGroup);
            app.MascaraAmarillaTab.Title = 'Mascara Amarilla';
            app.MascaraAmarilla = uiaxes(app.MascaraAmarillaTab);
            zlabel(app.MascaraAmarilla, 'Z')
            app.MascaraAmarillaLimpia = uiaxes(app.MascaraAmarillaTab);
            zlabel(app.MascaraAmarillaLimpia, 'Z')
            app.MascaraAmarillasinRuido = uiaxes(app.MascaraAmarillaTab);
            zlabel(app.MascaraAmarillasinRuido, 'Z')
            app.MascaraAmarillaLlenadoAgujeros = uiaxes(app.MascaraAmarillaTab);
            zlabel(app.MascaraAmarillaLlenadoAgujeros, 'Z')
            app.MASCARAINDIVIDUALAMARILLALabel = uilabel(app.MascaraAmarillaTab);
            app.MASCARAINDIVIDUALAMARILLALabel.Text = 'MASCARA INDIVIDUAL AMARILLA';
            app.MASCARALIMPIAAMARILLALabel = uilabel(app.MascaraAmarillaTab);
            app.MASCARALIMPIAAMARILLALabel.Text = 'MASCARA LIMPIA AMARILLA';
            app.LLENADODEAGUJEROSAMARILLALabel = uilabel(app.MascaraAmarillaTab);
            app.LLENADODEAGUJEROSAMARILLALabel.Text = 'LLENADO DE AGUJEROS AMARILLA';
            app.MASCARASINRUIDOAMARILLALabel = uilabel(app.MascaraAmarillaTab);
            app.MASCARASINRUIDOAMARILLALabel.Text = 'MASCARA SIN RUIDO AMARILLA';
            app.Image8_6 = uiimage(app.MascaraAmarillaTab);
            app.Image8_6.ImageSource = fullfile(pathToMLAPP, 'UPC_logo_transparente.png');
            app.PresentadoporJoannaCarrionKatherineCondorJenaCuevaLabel_6 = uilabel(app.MascaraAmarillaTab);
            app.PresentadoporJoannaCarrionKatherineCondorJenaCuevaLabel_6.Text = 'Presentado por: Joanna Carrión, Katherine Condor, Jean Cueva, Walther Retuerto, Anthony Santillán y Alexander Salazar                                                                                                                                       2024-02';
            app.MascaraAnaranjadaTab = uitab(app.TabGroup);
            app.MascaraAnaranjadaTab.Title = 'Mascara Anaranjada';
            app.MascaraNaranja = uiaxes(app.MascaraAnaranjadaTab);
            zlabel(app.MascaraNaranja, 'Z')
            app.MascaraNaranja.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.MascaraNaranja.YTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.MascaraNaranjasinRuido = uiaxes(app.MascaraAnaranjadaTab);
            zlabel(app.MascaraNaranjasinRuido, 'Z')
            app.MascaraNaranjaLlenadoAgujeros = uiaxes(app.MascaraAnaranjadaTab);
            zlabel(app.MascaraNaranjaLlenadoAgujeros, 'Z')
            app.MascaraNaranjaLimpia = uiaxes(app.MascaraAnaranjadaTab);
            zlabel(app.MascaraNaranjaLimpia, 'Z')
            app.MASCARAINDIVIDUALANARANJADALabel = uilabel(app.MascaraAnaranjadaTab);
            app.MASCARAINDIVIDUALANARANJADALabel.Text = 'MASCARA INDIVIDUAL ANARANJADA';
            app.MASCARALIMPIAANARANJADALabel = uilabel(app.MascaraAnaranjadaTab);
            app.MASCARALIMPIAANARANJADALabel.Text = 'MASCARA LIMPIA ANARANJADA';
            app.LLENADODEAGUJEROSANARANJADALabel = uilabel(app.MascaraAnaranjadaTab);
            app.LLENADODEAGUJEROSANARANJADALabel.Text = 'LLENADO DE AGUJEROS ANARANJADA';
            app.MASCARASINRUIDOANARANJADALabel = uilabel(app.MascaraAnaranjadaTab);
            app.MASCARASINRUIDOANARANJADALabel.Text = 'MASCARA SIN RUIDO ANARANJADA';
            app.Image8_7 = uiimage(app.MascaraAnaranjadaTab);
            app.Image8_7.ImageSource = fullfile(pathToMLAPP, 'UPC_logo_transparente.png');
            app.PresentadoporJoannaCarrionKatherineCondorJenaCuevaLabel_7 = uilabel(app.MascaraAnaranjadaTab);
            app.PresentadoporJoannaCarrionKatherineCondorJenaCuevaLabel_7.Text = 'Presentado por: Joanna Carrión, Katherine Condor, Jean Cueva, Walther Retuerto, Anthony Santillán y Alexander Salazar                                                                                                                                       2024-02';
            app.MascaraCelesteTab = uitab(app.TabGroup);
            app.MascaraCelesteTab.Title = 'Mascara Celeste';
            app.MascaraCeleste = uiaxes(app.MascaraCelesteTab);
            zlabel(app.MascaraCeleste, 'Z')
            app.MascaraCeleste.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.MascaraCeleste.YTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.MascaraCelestesinRuido = uiaxes(app.MascaraCelesteTab);
            zlabel(app.MascaraCelestesinRuido, 'Z')
            app.MascaraCelesteLlenadoAgujeros = uiaxes(app.MascaraCelesteTab);
            zlabel(app.MascaraCelesteLlenadoAgujeros, 'Z')
            app.MascaraCelesteLimpia = uiaxes(app.MascaraCelesteTab);
            zlabel(app.MascaraCelesteLimpia, 'Z')
            app.MASCARAINDIVIDUALCELESTELabel = uilabel(app.MascaraCelesteTab);
            app.MASCARAINDIVIDUALCELESTELabel.Text = 'MASCARA INDIVIDUAL CELESTE';
            app.MASCARALIMPIACELESTELabel = uilabel(app.MascaraCelesteTab);
            app.MASCARALIMPIACELESTELabel.Text = 'MASCARA LIMPIA CELESTE';
            app.LLENADODEAGUJEROSCELESTELabel = uilabel(app.MascaraCelesteTab);
            app.LLENADODEAGUJEROSCELESTELabel.Text = 'LLENADO DE AGUJEROS CELESTE';
            app.MASCARASINRUIDOCELESTELabel = uilabel(app.MascaraCelesteTab);
            app.MASCARASINRUIDOCELESTELabel.Text = 'MASCARA SIN RUIDO CELESTE';
            app.Image8_8 = uiimage(app.MascaraCelesteTab);
            app.Image8_8.ImageSource = fullfile(pathToMLAPP, 'UPC_logo_transparente.png');
            app.PresentadoporJoannaCarrionKatherineCondorJenaCuevaLabel_8 = uilabel(app.MascaraCelesteTab);
            app.PresentadoporJoannaCarrionKatherineCondorJenaCuevaLabel_8.Text = 'Presentado por: Joanna Carrión, Katherine Condor, Jean Cueva, Walther Retuerto, Anthony Santillán y Alexander Salazar                                                                                                                                       2024-02';
            app.MascaraMarrnTab = uitab(app.TabGroup);
            app.MascaraMarrnTab.Title = 'Mascara Marrón';
            app.MascaraMarron = uiaxes(app.MascaraMarrnTab);
            zlabel(app.MascaraMarron, 'Z')
            app.MascaraMarron.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.MascaraMarron.YTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.MascaraMarronsinRuido = uiaxes(app.MascaraMarrnTab);
            zlabel(app.MascaraMarronsinRuido, 'Z')
            app.MascaraMarronLlenadoAgujeros = uiaxes(app.MascaraMarrnTab);
            zlabel(app.MascaraMarronLlenadoAgujeros, 'Z')
            app.MascaraMarronLimpia = uiaxes(app.MascaraMarrnTab);
            zlabel(app.MascaraMarronLimpia, 'Z')
            app.MASCARAINDIVIDUALMARRNLabel = uilabel(app.MascaraMarrnTab);
            app.MASCARAINDIVIDUALMARRNLabel.Text = 'MASCARA INDIVIDUAL MARRÓN';
            app.MASCARALIMPIAMARRNLabel = uilabel(app.MascaraMarrnTab);
            app.MASCARALIMPIAMARRNLabel.Text = 'MASCARA LIMPIA MARRÓN';
            app.LLENADODEAGUJEROSMARRNLabel = uilabel(app.MascaraMarrnTab);
            app.LLENADODEAGUJEROSMARRNLabel.Text = 'LLENADO DE AGUJEROS MARRÓN';
            app.MASCARASINRUIDOMARRNLabel = uilabel(app.MascaraMarrnTab);
            app.MASCARASINRUIDOMARRNLabel.Text = 'MASCARA SIN RUIDO MARRÓN';
            app.Image8_9 = uiimage(app.MascaraMarrnTab);
            app.Image8_9.ImageSource = fullfile(pathToMLAPP, 'UPC_logo_transparente.png');
            app.PresentadoporJoannaCarrionKatherineCondorJenaCuevaLabel_9 = uilabel(app.MascaraMarrnTab);
            app.PresentadoporJoannaCarrionKatherineCondorJenaCuevaLabel_9.Text = 'Presentado por: Joanna Carrión, Katherine Condor, Jean Cueva, Walther Retuerto, Anthony Santillán y Alexander Salazar                                                                                                                                       2024-02';
            app.MascaraNegraTab = uitab(app.TabGroup);
            app.MascaraNegraTab.Title = 'Mascara Negra';
            app.MascaraNegra = uiaxes(app.MascaraNegraTab);
            zlabel(app.MascaraNegra, 'Z')
            app.MascaraNegra.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.MascaraNegra.YTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.MascaraNegrasinRuido = uiaxes(app.MascaraNegraTab);
            zlabel(app.MascaraNegrasinRuido, 'Z')
            app.MascaraNegraLlenadoAgujeros = uiaxes(app.MascaraNegraTab);
            zlabel(app.MascaraNegraLlenadoAgujeros, 'Z')
            app.MascaraNegraLimpia = uiaxes(app.MascaraNegraTab);
            zlabel(app.MascaraNegraLimpia, 'Z')
            app.MASCARAINDIVIDUALNEGRALabel = uilabel(app.MascaraNegraTab);
            app.MASCARAINDIVIDUALNEGRALabel.Text = 'MASCARA INDIVIDUAL NEGRA';
            app.MASCARALIMPIANEGRALabel = uilabel(app.MascaraNegraTab);
            app.MASCARALIMPIANEGRALabel.Text = 'MASCARA LIMPIA NEGRA';
            app.LLENADODEAGUJEROSNEGRALabel = uilabel(app.MascaraNegraTab);
            app.LLENADODEAGUJEROSNEGRALabel.Text = 'LLENADO DE AGUJEROS NEGRA';
            app.MASCARASINRUIDONEGRALabel = uilabel(app.MascaraNegraTab);
            app.MASCARASINRUIDONEGRALabel.Text = 'MASCARA SIN RUIDO NEGRA';
            app.Image8_10 = uiimage(app.MascaraNegraTab);
            app.Image8_10.ImageSource = fullfile(pathToMLAPP, 'UPC_logo_transparente.png');
            app.PresentadoporJoannaCarrionKatherineCondorJenaCuevaLabel_10 = uilabel(app.MascaraNegraTab);
            app.PresentadoporJoannaCarrionKatherineCondorJenaCuevaLabel_10.Text = 'Presentado por: Joanna Carrión, Katherine Condor, Jean Cueva, Walther Retuerto, Anthony Santillán y Alexander Salazar                                                                                                                                       2024-02';
            % Show the figure after all components are created
            app.EvaluaciondeDesempeoUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Interfaz_TF

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.EvaluaciondeDesempeoUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EvaluaciondeDesempeoUIFigure)
        end
    end
end